classdef CR5Teach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        JointAngleControlTab          matlab.ui.container.Tab
        JointControlLabel             matlab.ui.control.Label
        Q1SliderLabel                 matlab.ui.control.Label
        Q1Slider                      matlab.ui.control.Slider
        Q2SliderLabel                 matlab.ui.control.Label
        Q2Slider                      matlab.ui.control.Slider
        Q3SliderLabel                 matlab.ui.control.Label
        Q3Slider                      matlab.ui.control.Slider
        Q4SliderLabel                 matlab.ui.control.Label
        Q4Slider                      matlab.ui.control.Slider
        Q5SliderLabel                 matlab.ui.control.Label
        Q5Slider                      matlab.ui.control.Slider
        Q6SliderLabel                 matlab.ui.control.Label
        Q6Slider                      matlab.ui.control.Slider
        ResetButton                   matlab.ui.control.Button
        ResolvedMotionRateControlTab  matlab.ui.container.Tab
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        DestinationLabel              matlab.ui.control.Label
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        RollEditFieldLabel            matlab.ui.control.Label
        RollEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel           matlab.ui.control.Label
        PitchEditField                matlab.ui.control.NumericEditField
        YawEditFieldLabel             matlab.ui.control.Label
        YawEditField                  matlab.ui.control.NumericEditField
        PlotButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property; % Description\
        robot; % Robot
        q = zeros(1,6); % Joint states
        handle;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            L(1) = Link('d',0.147,'a',0,'alpha',pi/2,'offset',0,'qlim',[-deg2rad(180) deg2rad(180)]);
            L(2) = Link('d',0.025,'a',0.427,'alpha',0,'offset',pi/2,'qlim',[-deg2rad(90) deg2rad(90)]);
            L(3) = Link('d',0,'a',0.357,'alpha',0,'offset',0,'qlim',[-deg2rad(160) deg2rad(160)]);
            L(4) = Link('d',0.116,'a',0,'alpha',pi/2,'offset',pi/2,'qlim',[-deg2rad(180) deg2rad(180)]);
            L(5) = Link('d',0.116,'a',0,'alpha',-pi/2,'offset',0,'qlim',[-deg2rad(180) deg2rad(180)]);
            L(6) = Link('d',0.114,'a',0,'alpha',0,'offset',0,'qlim',[-deg2rad(360) deg2rad(360)]);
            
            app.robot = SerialLink(L,'name',['CR5_',datestr(now,'yyyymmddTHHMMSSFFF')]);
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            app.q(1) = deg2rad(event.Value);
            app.robot.plot(app.q);
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            app.q(2) = deg2rad(event.Value);
            app.robot.plot(app.q);
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            app.q(3) = deg2rad(event.Value);
            app.robot.plot(app.q);
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            app.q(4) = deg2rad(event.Value);
            app.robot.plot(app.q);
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            app.q(5) = deg2rad(event.Value);
            app.robot.plot(app.q); 
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            app.q(3) = deg2rad(event.Value);
            app.robot.plot(app.q);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.q = zeros(1,6);
            app.robot.plot(app.q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.JointAngleControlTab = uitab(app.TabGroup);
            app.JointAngleControlTab.Title = 'Joint Angle Control';
            app.JointControlLabel = uilabel(app.JointAngleControlTab);
            app.JointControlLabel.Text = 'Joint Control';
            app.Q1SliderLabel = uilabel(app.JointAngleControlTab);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.JointAngleControlTab);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q1Slider.MajorTickLabels = {'-180', '-90', '0', '90', '180'};
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.MinorTicks = [-180 -168 -156 -144 -132 -120 -108 -96 -84 -72 -60 -48 -36 -24 -12 0 12 24 36 48 60 72 84 96 108 120 132 144 156 168 180];
            app.Q2SliderLabel = uilabel(app.JointAngleControlTab);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.JointAngleControlTab);
            app.Q2Slider.Limits = [-90 90];
            app.Q2Slider.MajorTicks = [-90 -45 0 45 90];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q3SliderLabel = uilabel(app.JointAngleControlTab);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.JointAngleControlTab);
            app.Q3Slider.Limits = [-160 160];
            app.Q3Slider.MajorTicks = [-160 -80 0 80 160];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q4SliderLabel = uilabel(app.JointAngleControlTab);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.JointAngleControlTab);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q5SliderLabel = uilabel(app.JointAngleControlTab);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.JointAngleControlTab);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.MajorTicks = [-180 -90 0 90 180];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q6SliderLabel = uilabel(app.JointAngleControlTab);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.JointAngleControlTab);
            app.Q6Slider.Limits = [-360 360];
            app.Q6Slider.MajorTicks = [-360 -180 0 180 360];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.ResetButton = uibutton(app.JointAngleControlTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResolvedMotionRateControlTab = uitab(app.TabGroup);
            app.ResolvedMotionRateControlTab.Title = 'Resolved Motion Rate Control';
            app.XEditFieldLabel = uilabel(app.ResolvedMotionRateControlTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ResolvedMotionRateControlTab, 'numeric');
            app.DestinationLabel = uilabel(app.ResolvedMotionRateControlTab);
            app.DestinationLabel.Text = 'Destination';
            app.YEditFieldLabel = uilabel(app.ResolvedMotionRateControlTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ResolvedMotionRateControlTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ResolvedMotionRateControlTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ResolvedMotionRateControlTab, 'numeric');
            app.RollEditFieldLabel = uilabel(app.ResolvedMotionRateControlTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.ResolvedMotionRateControlTab, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.ResolvedMotionRateControlTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.ResolvedMotionRateControlTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.ResolvedMotionRateControlTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.ResolvedMotionRateControlTab, 'numeric');
            app.PlotButton = uibutton(app.ResolvedMotionRateControlTab, 'push');
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CR5Teach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end